classdef RadarApp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        RecordTab                      matlab.ui.container.Tab
        StartButton                    matlab.ui.control.Button
        StopButton                     matlab.ui.control.Button
        EnterFilenameEditFieldLabel    matlab.ui.control.Label
        NameRecording                  matlab.ui.control.EditField
        StatusEditFieldLabel           matlab.ui.control.Label
        StatusEditField                matlab.ui.control.EditField
        DopplerTab                     matlab.ui.container.Tab
        DisplayDopplerMapButton        matlab.ui.control.Button
        EnterFilenameEditFieldLabel_2  matlab.ui.control.Label
        Doppleredit                    matlab.ui.control.EditField
        UIAxes2                        matlab.ui.control.UIAxes
        RTITab                         matlab.ui.container.Tab
        DisplayRTIMapButton            matlab.ui.control.Button
        EnterFilenameEditField_2Label  matlab.ui.control.Label
        RTIedit                        matlab.ui.control.EditField
        UIAxes2_2                      matlab.ui.control.UIAxes
        AnalyzeTab                     matlab.ui.container.Tab
        AnalyzeReturnsButton           matlab.ui.control.Button
        EnterFileNameEditFieldLabel    matlab.ui.control.Label
        EnterFileNameEditField         matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        CorrelationCoefficientEditFieldLabel  matlab.ui.control.Label
        CorrelationCoefficientEditField  matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: AnalyzeReturnsButton
        function AnalyzeReturnsButtonPushed(app, event)
            wavFile = app.EnterFileNameEditField.Value;
            [Profiles Res Tp dataRange Fs] = cantenna_rti_v3_yunus(wavFile);
            [AlignedData BinShift] = RA_correlation_v5(Profiles.',0,4);
            aligned_data = AlignedData(:,1:end-50);
            BinShift = BinShift(1:end-50);
            %Find Distance from radar for each return and compensate data
            Range_shift = BinShift*Res;
            R3 = (abs(Range_shift)).^3;
            aligned_dataR3 = bsxfun(@times,aligned_data,R3);    

            %Get index over range and time for target window
            [Ridx Tidx] = TargetFocus(aligned_data);
            TargetWindow = aligned_dataR3(Ridx,:);
            %Correlation Coefficient
            TimeFocused = aligned_dataR3(:,Tidx);
            for i = 1:size(TimeFocused)
                CorrCoeff(i) = corr2(abs(TimeFocused(:,i)),abs(TimeFocused(:,i+1)));
            end
            
            %Sum Profiles over range window to give vector 
            SumTarget = sum(TargetWindow,1);
            FocusedReturn = SumTarget(Tidx).';
            y = abs(FocusedReturn);
            NumSamplesTarget = size(FocusedReturn,1);
            TargetNorm = normalize(FocusedReturn);
            
            [Fit, Vector, Data] = fitRCS(TargetNorm);
            plot(app.UIAxes,Vector,Fit,'-g',Vector,Data,'*b');


            app.CorrelationCoefficientEditField.Value = mean(CorrCoeff);
        end

        % Value changed function: EnterFileNameEditField
        function EnterFileNameEditFieldValueChanged(app, event)
            value = app.EnterFileNameEditField.Value;
            
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            global RecObj
            RecObj = audiorecorder(44000,16,2);         %Create Recording Object
            record(RecObj);                             %Start Recording
            status = isrecording(RecObj);
           
            app.StatusEditField.Value = 'Recording in Progress';
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global RecObj
            stop(RecObj);
            samples = get(RecObj,'TotalSamples');
            SampleRate = get(RecObj,'SampleRate');
            Channels = get(RecObj,'NumberOfChannels');
            data = getaudiodata(RecObj);
            RecName = app.NameRecording.Value;
            fileName = [RecName '.wav'];
            audiowrite(fileName,data,SampleRate);
            app.StatusEditField.Value = 'Recording Complete';
            
            
        end

        % Value changed function: NameRecording
        function NameRecordingValueChanged(app, event)
            value = app.NameRecording.Value;
            
        end

        % Value changed function: RTIedit
        function RTIeditValueChanged(app, event)
            value = app.RTIedit.Value;
            
        end

        % Button pushed function: DisplayRTIMapButton
        function DisplayRTIMapButtonPushed(app, event)
            rti = app.RTIedit.Value;
            [Profiles Res Tp dataRange Fs numPulses] = cantenna_rti_v3_yunus(rti);
            fig = imagesc(dataRange,(1:numPulses)*Tp*2,20*log10(abs(Profiles)));
            set(gca,'Ydir','reverse');
            saveas(fig,'RTI.png')
            
            imshow('RTI.png', 'Parent', app.UIAxes2_2);
            
        end

        % Button pushed function: DisplayDopplerMapButton
        function DisplayDopplerMapButtonPushed(app, event)
            dopp = app.Doppleredit.Value;
            [t v d] = cantenna_dop_v3_yunus(dopp);
            fig2 = imagesc(t, v, d');
            %set(gca,'Ydir','reverse');
            colormap(jet(256));
            saveas(fig2,'Dop.png');
            
            imshow('Dop.png','Parent', app.UIAxes2);
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.RecordTab = uitab(app.TabGroup);
            app.RecordTab.Title = 'Record';
            app.StartButton = uibutton(app.RecordTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.RecordTab, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.EnterFilenameEditFieldLabel = uilabel(app.RecordTab);
            app.EnterFilenameEditFieldLabel.Text = 'Enter Filename';
            app.NameRecording = uieditfield(app.RecordTab, 'text');
            app.NameRecording.ValueChangedFcn = createCallbackFcn(app, @NameRecordingValueChanged, true);
            app.StatusEditFieldLabel = uilabel(app.RecordTab);
            app.StatusEditFieldLabel.Text = 'Status';
            app.StatusEditField = uieditfield(app.RecordTab, 'text');
            app.DopplerTab = uitab(app.TabGroup);
            app.DopplerTab.Title = 'Doppler';
            app.DisplayDopplerMapButton = uibutton(app.DopplerTab, 'push');
            app.DisplayDopplerMapButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayDopplerMapButtonPushed, true);
            app.DisplayDopplerMapButton.Text = 'Display Doppler Map';
            app.EnterFilenameEditFieldLabel_2 = uilabel(app.DopplerTab);
            app.EnterFilenameEditFieldLabel_2.Text = 'Enter Filename';
            app.Doppleredit = uieditfield(app.DopplerTab, 'text');
            app.UIAxes2 = uiaxes(app.DopplerTab);
            title(app.UIAxes2, 'Doppler Map')
            xlabel(app.UIAxes2, 'Time [s]')
            ylabel(app.UIAxes2, 'Velocity [m/s]')
            app.RTITab = uitab(app.TabGroup);
            app.RTITab.Title = 'RTI';
            app.DisplayRTIMapButton = uibutton(app.RTITab, 'push');
            app.DisplayRTIMapButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayRTIMapButtonPushed, true);
            app.DisplayRTIMapButton.Text = 'Display RTI Map';
            app.EnterFilenameEditField_2Label = uilabel(app.RTITab);
            app.EnterFilenameEditField_2Label.Text = 'Enter Filename';
            app.RTIedit = uieditfield(app.RTITab, 'text');
            app.RTIedit.ValueChangedFcn = createCallbackFcn(app, @RTIeditValueChanged, true);
            app.UIAxes2_2 = uiaxes(app.RTITab);
            title(app.UIAxes2_2, 'RTI Map')
            xlabel(app.UIAxes2_2, 'Range [m]')
            ylabel(app.UIAxes2_2, 'Time [s]')
            app.AnalyzeTab = uitab(app.TabGroup);
            app.AnalyzeTab.Title = 'Analyze';
            app.AnalyzeReturnsButton = uibutton(app.AnalyzeTab, 'push');
            app.AnalyzeReturnsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeReturnsButtonPushed, true);
            app.AnalyzeReturnsButton.Text = 'Analyze Returns';
            app.EnterFileNameEditFieldLabel = uilabel(app.AnalyzeTab);
            app.EnterFileNameEditFieldLabel.Text = 'Enter File Name';
            app.EnterFileNameEditField = uieditfield(app.AnalyzeTab, 'text');
            app.EnterFileNameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterFileNameEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.AnalyzeTab);
            title(app.UIAxes, 'RCS histogram')
            xlabel(app.UIAxes, 'RCS')
            ylabel(app.UIAxes, 'PDF')
            app.CorrelationCoefficientEditFieldLabel = uilabel(app.AnalyzeTab);
            app.CorrelationCoefficientEditFieldLabel.Text = 'Correlation Coefficient';
            app.CorrelationCoefficientEditField = uieditfield(app.AnalyzeTab, 'numeric');
        end
    end

    methods (Access = public)

        % Construct app
        function app = RadarApp1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end